classdef Visualizing_GUI_3333 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        StartButton_2             matlab.ui.control.StateButton
        Label2                    matlab.ui.control.Label
        RPMLabel                  matlab.ui.control.Label
        BPMLabel                  matlab.ui.control.Label
        LableLabel                matlab.ui.control.Label
        EditOrderEditField        matlab.ui.control.NumericEditField
        EditOrderEditFieldLabel   matlab.ui.control.Label
        LowpassfcEditField        matlab.ui.control.NumericEditField
        LowpassfcEditFieldLabel   matlab.ui.control.Label
        HighpassfcEditField       matlab.ui.control.NumericEditField
        HighpassfcEditFieldLabel  matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


     properties (Access = private)
        a 
        f
        f1
        Y
        Y1
        P1
        P2
        P3
        P4
        filteredPlot
        rawPlot
        serialport
        Fs = 8000;
        fs = 4000;
        fc1 = 30;
        fc2 = 200;
        T = 1/5000;
        n = 1;
        m = 1;
        m1 = 1;
        plotWindow = 2000;
        IsRunning = false;
        Timer
        Data
        ffData
        fdata 
        coefficient_1
        coefficient_2
        fc3=30
        bpm
        coefficient_3
        coefficient_4
        orderOfFilter=4;
        timeAtPointBPM;
        numPeaks;
        pks;
        locks;
        end

    methods (Access = private)
        
        function timerCallback = func(app)
            if ~app.IsRunning
            stop(app.Timer);
        end
    end
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsRunning=true;
            app.Timer=timer;
            app.Timer.Period=0.1;
            app.Timer.ExecutionMode='fixedRate';
            app.Timer.TimerFcn=@(~,~)app.timerCallback;
            start(app.Timer);

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsRunning = true;
            app.a = serialport("COM3", 230400);
            configureTerminator(app.a, "CR/LF");
            flush(app.a);
            app.a.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);
            app.IsRunning = true;
            
            while app.a.UserData.Order < 100000
                    app.Data = readline(app.a);
                    app.a.UserData.Data(end+1) = str2double(app.Data);
                    app.a.UserData.Order = app.a.UserData.Order + 1;
                if mod(app.a.UserData.Order, 10) == 0
                    configureCallback(app.a, "off");

                    plot(app.UIAxes, app.a.UserData.Data(max(1,end-app.plotWindow+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.fs/2), 'high');
        app.a.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.a.UserData.Data(1:end));
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1/(app.fs/2), app.fc2/(app.fs/2)], 'bandpass');

                    app.a.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.a.UserData.Data);
                    
                    plot(app.UIAxes_2, app.a.UserData.fData(max(1, end-app.plotWindow+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.a, "CR/LF");

                end
                 if mod(app.a.UserData.Order,500)==0
                     configureCallback(app.a, "off");

                     app.Y = fft(app.a.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); % Use 400 for normalization
                     app.f = app.fs * (0:(length(app.Y)/2))/length(app.Y); % Adjust frequency axis
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','r');
                     set(gca,'ylim',[0,10]);

                     app.Y1 = fft(app.a.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y1 / 500); % Use 400 for normalization
                     app.f1 = app.fs * (0:(length(app.Y1)/2))/length(app.Y1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','r');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.a,"CR/LF");
                 end
                   if mod(app.a.UserData.Order, 1500) == 0
                     % Measure elapsed time
                    app.timeAtPointBPM = toc;
                    % Reset the timer immediately for the next interval
                     
                
                    % Detect peaks in the data
                    threshold = 371; % Adjust this threshold as needed
                    [peaks, ~] = findpeaks(app.a.UserData.Data(end-1499+1:end), 'MinPeakHeight', threshold);
                    app.numPeaks = numel(peaks); % Count the number of peaks
                   end 
                    
                   % Örnek sinyal (kendi verinizi buraya koyabilirsiniz)
app.fs = 1000; % Örnekleme frekansı (Hz)
time = 0:1/app.fs:10; % Zaman ekseni (0-10 saniye arası)
signal = sin(2*pi*1*time) + 0.5*randn(size(time)); % Örnek bir sinyal

% Sinyaldeki tepe noktalarını bulma
[app.pks, locs] = findpeaks(signal, 'MinPeakHeight', 0.5, 'MinPeakDistance', app.fs/2); 

% Tepe noktalarına karşılık gelen zamanları hesaplama
peakTimes = time(locs); 

% BPM hesaplama
app.numPeaks = length(peakTimes); % Tepe noktalarının sayısı
totalTime = time(end) - time(1); % Toplam süre (saniye cinsinden)
app.bpm = (app.numPeaks / totalTime) * 60; % BPM hesabı

 app.LableLabel.Text = num2str(app.bpm);
            end 
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsRunning=false;
            delete(app.a);
        end

        % Value changed function: LowpassfcEditField
        function LowpassfcEditFieldValueChanged(app, event)
            b=app.LowpassfcEditField.Value;
            app.fc2=b;
        end

        % Value changed function: HighpassfcEditField
        function HighpassfcEditFieldValueChanged(app, event)
             c= app.HighpassfcEditField.Value;
             app.fc1=c;
        end

        % Value changed function: EditOrderEditField
        function EditOrderEditFieldValueChanged(app, event)
            j = app.EditOrderEditField.Value;
            app.orderOfFilter=j;
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            app.IsRunning = true;
            app.a = serialport("COM3",230400);
            configureTerminator(app.a, "CR/LF");
            flush(app.a);
            app.a.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);
            app.IsRunning = true;
            
            while app.a.UserData.Order < 100000
                    app.Data = readline(app.a);
                    app.a.UserData.Data(end+1) = str2double(app.Data);
                    app.a.UserData.Order = app.a.UserData.Order + 1;
                if mod(app.a.UserData.Order, 10) == 0
                    configureCallback(app.a, "off");

                    plot(app.UIAxes, app.a.UserData.Data(max(1,end-app.plotWindow+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.fs/2), 'high');
        app.a.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.a.UserData.Data(1:end));
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1/(app.fs/2), app.fc2/(app.fs/2)], 'bandpass');

                    app.a.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.a.UserData.Data);
                    
                    plot(app.UIAxes_2, app.a.UserData.fData(max(1, end-app.plotWindow+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.a, "CR/LF");

                end
                 if mod(app.a.UserData.Order,500)==0
                     configureCallback(app.a, "off");

                     app.Y = fft(app.a.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); % Use 400 for normalization
                     app.f = app.fs * (0:(length(app.Y)/2))/length(app.Y); % Adjust frequency axis
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','y');
                     set(gca,'ylim',[0,10]);

                     app.Y1 = fft(app.a.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y1 / 500); % Use 400 for normalization
                     app.f1 = app.fs * (0:(length(app.Y1)/2))/length(app.Y1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','y');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.a,"CR/LF");
                 end
            end

            % Örnek Solunum Sinyali (Bu kısımda gerçek verilerinizi kullanmalısınız)
app.fs = 100; % Örnekleme frekansı (Hz)
t = 0:1/app.fs:60; % 60 saniyelik zaman aralığı
respiratory_signal = sin(2*pi*0.25*t) + 0.1*randn(size(t)); % Örnek sinyal (0.25 Hz = 15 nefes/dakika)

% Sinyal Filtreleme (İsteğe Bağlı)
fc_low = 0.1; % Düşük frekans sınırı (Hz)
fc_high = 1;  % Yüksek frekans sınırı (Hz)
[b, app.a] = butter(2, [fc_low, fc_high]/(app.fs/2), 'bandpass'); % Bandpass filtresi
filtered_signal = filter(b, app.a, respiratory_signal);

% Tepe Noktalarını Tespit Etme
[peaks] = findpeaks(filtered_signal, 'MinPeakDistance', app.fs/2); % MinPeakDistance ile minimum 2 saniye aralıkta tepe bulunur

% Solunum Hızını Hesaplama
num_breaths = length(peaks); % Toplam nefes sayısı
duration_in_minutes = (t(end) - t(1)) / 60; % Toplam süre (dakika cinsinden)
respiratory_rate = num_breaths / duration_in_minutes; % Solunum hızı (nefes/dakika)


% Sonuçları Yazdırma
app.Label2.Text = num2str(app.respiratory_rate); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Raw Signal FFT')
            xlabel(app.UIAxes_3, 'Frequency')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered Signal FFT')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HighpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassfcEditFieldLabel.Text = 'High pass fc';
            app.HighpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpassfcEditField.Limits = [20 200];
            app.HighpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @HighpassfcEditFieldValueChanged, true);
            app.HighpassfcEditField.Value = 20;
            app.LowpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassfcEditFieldLabel.Text = 'Low pass fc';
            app.LowpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassfcEditField.Limits = [20 2000];
            app.LowpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassfcEditFieldValueChanged, true);
            app.LowpassfcEditField.Value = 300;
            app.EditOrderEditFieldLabel = uilabel(app.UIFigure);
            app.EditOrderEditFieldLabel.Text = 'Edit Order';
            app.EditOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditOrderEditField.Limits = [1 4];
            app.EditOrderEditField.ValueChangedFcn = createCallbackFcn(app, @EditOrderEditFieldValueChanged, true);
            app.EditOrderEditField.Value = 2;
            app.LableLabel = uilabel(app.UIFigure);
            app.LableLabel.Text = 'Lable';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.StartButton_2 = uibutton(app.UIFigure, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Text = 'Start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'lung.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'heart.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualizing_GUI_3333

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end